classdef enigma_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SwitchButton                  matlab.ui.control.Button
        ClearButton                   matlab.ui.control.Button
        PlaintextTextArea             matlab.ui.control.TextArea
        PlaintextTextAreaLabel        matlab.ui.control.Label
        CiphertextTextArea            matlab.ui.control.TextArea
        CiphertextTextAreaLabel       matlab.ui.control.Label
        EncryptButton                 matlab.ui.control.Button
        SimplifiedEnigmaMachineLabel  matlab.ui.control.Label
        EnigmaSettingsPanel           matlab.ui.container.Panel
        GridLayout                    matlab.ui.container.GridLayout
        PlugboardTextArea             matlab.ui.control.TextArea
        PlugboardTextAreaLabel        matlab.ui.control.Label
        RotorposPanel                 matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        Rotor3EditField               matlab.ui.control.NumericEditField
        Rotor3EditFieldLabel          matlab.ui.control.Label
        Rotor2EditField               matlab.ui.control.NumericEditField
        Rotor2EditFieldLabel          matlab.ui.control.Label
        Rotor1EditField               matlab.ui.control.NumericEditField
        Rotor1EditFieldLabel          matlab.ui.control.Label
        ModelListBox                  matlab.ui.control.ListBox
        ModelListBoxLabel             matlab.ui.control.Label
        Image                         matlab.ui.control.Image
    end

    
    properties (Access = public)
        % Setarile
        model; rotor_pos1; rotor_pos2; rotor_pos3; plugboard; text; rotor1; rotor2; rotor3; reflector; 
        ok_run = 1;
    end
    
    methods (Access = public)
        
        function get_data(app)
            % Initializez variabilele cu care lucrez
            app.model = app.ModelListBox.Value;
            app.rotor_pos1 = app.Rotor1EditField.Value;
            app.rotor_pos2 = app.Rotor2EditField.Value;
            app.rotor_pos3 = app.Rotor3EditField.Value;
            app.plugboard = char(app.PlugboardTextArea.Value);
            app.text = char(app.PlaintextTextArea.Value);
        end
        
        function model_set(app)
            % Aleg modelul de Enigma
            if app.model == "Enigma I"
                app.rotor1 = 'EKMFLGDQVZNTOWYHXUSPAIBRCJ';
                app.rotor2 = 'AJDKSIRUXBLHWTMCQGZNPYFVOE';
                app.rotor3 = 'BDFHJLCPRTXVZNYEIWGAKMUSQO';
                app.reflector = 'EJMZALYXVBWFCRQUONTSPIKHGD';
            elseif app.model == "Enigma M3"
                app.rotor1 = 'EKMFLGDQVZNTOWYHXUSPAIBRCJ';
                app.rotor2 = 'ESOVPZJAYQUIRHXLNFTGKDCMWB';
                app.rotor3 = 'VZBRGITYUPSDNHLXAWMJQOFECK';
                app.reflector = 'YRUHQSLDPXNGOKMIEBFZCWVJAT';
            elseif app.model == "Swiss K"
                app.rotor1 = 'PEZUOHXSCVFMTBGLRINQJWAYDK';
                app.rotor2 = 'ZOUESYDKFWPCIQXHMVBLGNJRAT';
                app.rotor3 = 'EHRVXGAOBQUSIMZFLYNWKTPDJC';
                app.reflector = 'IMETCGFRAYSQBZXWLHKDVUPOJN';
            end 
        end
        
        function remove_space(app)
            % Functie de a scoate spatiile pentru plugboard
            app.plugboard = strrep(app.plugboard, ' ', '');
        end
        
        function ok = plugboard_valid(app)
            % Functie de a verifica daca plugboardul este valid
            app.plugboard = upper(app.plugboard);
            ok= 1;
            for i = 1 : length(app.plugboard) - 1
                for j = (i + 1) : length(app.plugboard)
                    if app.plugboard(i) == app.plugboard(j) || app.plugboard(j) < 'A' || app.plugboard(j) > 'Z' || app.plugboard(i) < 'A' || app.plugboard(i) > 'Z'
                        ok = 0;
                    end
                end
            end
        end

        function check_plugboard(app)
            % Functie ce modifica aplicatia in functie de plugboard
            remove_space(app);
            if ~(plugboard_valid(app) == 1 && mod(length(app.plugboard), 2) == 0 && length(app.plugboard) <= 26)
                app.ok_run = 0;
                app.CiphertextTextArea.Value = 'Plugboard ales incorect!';
                app.CiphertextTextArea.FontColor = [1, 0, 0];
            end      
        end

        function output = encryption(app, letter, rotor)
            alphabet = 'ABCDEFGHIJKLMNOPQRSTUVWXYZ';
            
            % Alegerea literei in functie de rotor
             output = rotor(alphabet == letter);
        
        end
        
        function output = reflection(app, letter, rotor)
            alphabet = 'ABCDEFGHIJKLMNOPQRSTUVWXYZ';
        
            % Oglindirea criptarii
            output = alphabet(rotor == letter);
        
        end

        function plugboard = plugboard_swap(app, input)
            % Setarea plugboardului de forma unui alfabet interschimbat
            plugboard = 'ABCDEFGHIJKLMNOPQRSTUVWXYZ';
            for i = 1 : 2 : length(input)
                plugboard(find(plugboard == input(i+1))) = '0';
                plugboard(find(plugboard == input(i))) = input(i+1);
                plugboard(find(plugboard == '0')) = input(i);
            end
        end

        function encrypted_text = enigma_encryption(app)

            % Definirea alfabetului pentru masina Enigma
            alphabet = 'ABCDEFGHIJKLMNOPQRSTUVWXYZ';
            
            app.plugboard = plugboard_swap(app, app.plugboard);

            % Convertirea textului in litere de tipar
            app.text = upper(app.text);
        
            % Eliminarea caracterelor invalide
            app.text(app.text < 'A' | app.text > 'Z') = '';
            
            % Criptarea mesajului conform setarilor
            encrypted_text = '';
            
            app.rotor1 = circshift(app.rotor1, -app.rotor_pos1);
            app.rotor2 = circshift(app.rotor2, -app.rotor_pos2);
            app.rotor3 = circshift(app.rotor3, -app.rotor_pos3);
        
            first1 = app.rotor1(1);
            first2 = app.rotor2(1);
        
            for i = 1 : length(app.text)
                % Criptarea litera cu litera
                letter = app.text(i);
                
                letter = encryption(app, letter, app.plugboard);
                letter = encryption(app, letter, app.rotor1);
                letter = encryption(app, letter, app.rotor2);
                letter = encryption(app, letter, app.rotor3);
        
                letter = app.reflector(alphabet == letter);
        
                letter = reflection(app, letter, app.rotor3);
                letter = reflection(app, letter, app.rotor2);
                letter = reflection(app, letter, app.rotor1);
                letter = reflection(app, letter, app.plugboard);
        
                % Adaugarea literei in textul criptat
                encrypted_text = [encrypted_text, letter];
                
                % Adaugarea spatiilor pentru a complica mesajul criptat
                if mod(i,5) == 0
                    encrypted_text = [encrypted_text, ' '];
                end
        
                % Modificarea pozitiei rotorilor
                app.rotor1 = circshift(app.rotor1, -1);
                if app.rotor1(1) == first1
                    app.rotor2 = circshift(app.rotor2, -1);
                    if app.rotor2(1) == first2
                        app.rotor3 = circshift(app.rotor3, -1);
                    end
                end
            end
        end

        function clear_input(app)
            app.PlaintextTextArea.Value = '';
            
        end
        
        function clear_output(app)
            app.CiphertextTextArea.Value = '';
            
        end
        
        function swap_in_out(app)
            aux = char(app.PlaintextTextArea.Value);
            aux = upper(aux);
            aux1 = '';
            aux2 = '';
            for i = 1:length(aux)
                if aux(i) ~= ' '
                    aux1 = [aux1 aux(i)];
                end
            end
            for i = 1:length(aux1)
                aux2 = [aux2, aux1(i)];
                if mod(i,5)==0 
                    aux2 = [aux2 ' '];
                end
            end
            app.PlaintextTextArea.Value = app.CiphertextTextArea.Value;
            app.CiphertextTextArea.Value = aux2;
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EncryptButton
        function EncryptButtonPushed(app, event)
            % Sunet
            [y, Fs] = audioread('Mechanical-button-click-sound-effect.mp3');
            sound(y, Fs, 16);
            
            % Conditiile initiale
            app.ok_run = 1;
            app.CiphertextTextArea.FontColor = [1, 1, 1];

            % De la pornirea aplicatiei
            get_data(app);
            model_set(app);
            check_plugboard(app);
            if app.ok_run == 1
                app.CiphertextTextArea.Value = enigma_encryption(app);
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            % Sunet
            [y, Fs] = audioread('Mechanical-button-click-sound-effect.mp3');
            sound(y, Fs, 16);
            clear_input(app);
            clear_output(app);
        end

        % Button pushed function: SwitchButton
        function SwitchButtonPushed(app, event)
            % Sunet
            [y, Fs] = audioread('Mechanical-button-click-sound-effect.mp3');
            sound(y, Fs, 16);
            swap_in_out(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'resources', 'brown-wooden-flooring.png');
            app.EnigmaSettingsPanel = uipanel(app.UIFigure);
            app.EnigmaSettingsPanel.Title = 'Enigma Settings';
            app.GridLayout = uigridlayout(app.EnigmaSettingsPanel);
            app.ModelListBoxLabel = uilabel(app.GridLayout);
            app.ModelListBoxLabel.Text = 'Model';
            app.ModelListBox = uilistbox(app.GridLayout);
            app.ModelListBox.Items = {'Enigma I', 'Enigma M3', 'Swiss K'};
            app.ModelListBox.Value = 'Enigma I';
            app.RotorposPanel = uipanel(app.GridLayout);
            app.RotorposPanel.Title = 'Rotor pos';
            app.GridLayout2 = uigridlayout(app.RotorposPanel);
            app.Rotor1EditFieldLabel = uilabel(app.GridLayout2);
            app.Rotor1EditFieldLabel.Text = 'Rotor1';
            app.Rotor1EditField = uieditfield(app.GridLayout2, 'numeric');
            app.Rotor1EditField.Limits = [1 26];
            app.Rotor1EditField.Value = 1;
            app.Rotor2EditFieldLabel = uilabel(app.GridLayout2);
            app.Rotor2EditFieldLabel.Text = 'Rotor2';
            app.Rotor2EditField = uieditfield(app.GridLayout2, 'numeric');
            app.Rotor2EditField.Limits = [1 26];
            app.Rotor2EditField.Value = 1;
            app.Rotor3EditFieldLabel = uilabel(app.GridLayout2);
            app.Rotor3EditFieldLabel.Text = 'Rotor3';
            app.Rotor3EditField = uieditfield(app.GridLayout2, 'numeric');
            app.Rotor3EditField.Limits = [1 26];
            app.Rotor3EditField.Value = 1;
            app.PlugboardTextAreaLabel = uilabel(app.GridLayout);
            app.PlugboardTextAreaLabel.Text = 'Plugboard';
            app.PlugboardTextArea = uitextarea(app.GridLayout);
            app.SimplifiedEnigmaMachineLabel = uilabel(app.UIFigure);
            app.SimplifiedEnigmaMachineLabel.Text = 'Simplified Enigma Machine';
            app.EncryptButton = uibutton(app.UIFigure, 'push');
            app.EncryptButton.ButtonPushedFcn = createCallbackFcn(app, @EncryptButtonPushed, true);
            app.EncryptButton.Text = 'Encrypt';
            app.CiphertextTextAreaLabel = uilabel(app.UIFigure);
            app.CiphertextTextAreaLabel.Text = 'Ciphertext';
            app.CiphertextTextArea = uitextarea(app.UIFigure);
            app.PlaintextTextAreaLabel = uilabel(app.UIFigure);
            app.PlaintextTextAreaLabel.Text = 'Plaintext';
            app.PlaintextTextArea = uitextarea(app.UIFigure);
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.SwitchButton = uibutton(app.UIFigure, 'push');
            app.SwitchButton.ButtonPushedFcn = createCallbackFcn(app, @SwitchButtonPushed, true);
            app.SwitchButton.Text = 'Switch';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = enigma_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end